classdef channel_coding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI         matlab.ui.Figure
        Panel_4     matlab.ui.container.Panel
        Panel       matlab.ui.container.Panel
        UIAxes      matlab.ui.control.UIAxes
        Button_2    matlab.ui.control.Button
        Panel_3     matlab.ui.container.Panel
        Label_9     matlab.ui.control.Label
        DropDown    matlab.ui.control.DropDown
        Label_12    matlab.ui.control.Label
        Spinner     matlab.ui.control.Spinner
        Label_13    matlab.ui.control.Label
        DropDown_2  matlab.ui.control.DropDown
        Label_14    matlab.ui.control.Label
        DropDown_3  matlab.ui.control.DropDown
        Button      matlab.ui.control.Button
        Panel_2     matlab.ui.container.Panel
        UIAxes_2    matlab.ui.control.UIAxes
        UIAxes_3    matlab.ui.control.UIAxes
        Label       matlab.ui.control.Label
        Label_2     matlab.ui.control.Label
        Label_11    matlab.ui.control.Label
        EditField   matlab.ui.control.EditField
    end

    
    properties (Access = private)
        gray_I; % 输入图像的灰度表示
        p;% 错误转移概率
        chan_mod;% 信道类型
        encd;% 信源编码方式
        dec_mod; % 信道译码方式
    end
    

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.EditField.Value = '正在传输中，请稍等...';
            
            % 清屏
            imshow(1, 'Parent', app.UIAxes_2);
            imshow(1, 'Parent', app.UIAxes_3);
            
            app.Label.Text = '误码率：';
            app.Label_2.Text = '误码率：';
            drawnow;
            
            [imageH, ~, Pe] = gui_channel_coding(app.gray_I, app.p, app.encd, app.chan_mod, app.dec_mod);
            
            imshow(uint8(imageH{1}), 'Parent', app.UIAxes_2);
            imshow(uint8(imageH{2}), 'Parent', app.UIAxes_3);
            
            app.Label.Text = strcat('误码率：', num2str(Pe(1)));
            app.Label_2.Text = strcat('误码率：', num2str(Pe(2)));
            
            app.EditField.Value = '传输完成';
            drawnow;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [fn, pn, ~] = uigetfile('*.bmp;*.tif;*.jpg;*.jpeg;*.png;*.gif;','请选择需要显示的图像');
            I = imread([pn fn]);
            [~, ~, c] = size(I);
            if c == 3 % 判断输入图像是否为灰度图
                app.gray_I = rgb2gray(I);
            else
                app.gray_I = I;
            end
            imshow(app.gray_I, 'Parent', app.UIAxes);
            
            % 清屏
            imshow(1, 'Parent', app.UIAxes_2);
            imshow(1, 'Parent', app.UIAxes_3);
            
            app.Label.Text = '误码率：';
            app.Label_2.Text = '误码率：';
            drawnow;
            
            % 设置默认值，即如果不点击其他按钮时，其他按钮的状态
            % 判断信道译码方式
            value = app.DropDown.Value;
            switch value
                case '极大似然译码准则'
                    app.dec_mod = 1;
                case '最大后验概率译码准则'
                    app.dec_mod = 2;
            end
            % 判断信道类型
            value = app.DropDown_3.Value;
            switch value
                case '二元对称信道'
                    app.chan_mod = 1;
                case '二元删除信道'
                    app.chan_mod = 2;
            end
            
            % 指定信道错误转移概率
            app.p = app.Spinner.Value;
            
            % 判断信源编码方式
            value = app.DropDown_2.Value;
            switch value
                case '香农编码'
                    app.encd = 1;
                case '费诺编码'
                    app.encd = 2;
                case '霍夫曼编码'
                    app.encd = 3;
            end 
            
            app.EditField.Value = '请选择参数...';
            drawnow;
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            
            switch value
                case '香农编码'
                    app.encd = 1;
                case '费诺编码'
                    app.encd = 2;
                case '霍夫曼编码'
                    app.encd = 3;
            end 
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            
            switch value
                case '二元对称信道'
                    app.chan_mod = 1;
                case '二元删除信道'
                    app.chan_mod = 2;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
            switch value
                case '极大似然译码准则'
                    app.dec_mod = 1;
                case '最大后验概率译码准则'
                    app.dec_mod = 2;
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            
            app.p = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure;
            app.GUI.Name = 'UI Figure';
            app.Panel_4 = uipanel(app.GUI);
            app.Panel_4.Title = '有无信道编码对传输结果的影响';
            app.Panel = uipanel(app.Panel_4);
            app.Panel.Title = '输入图像';
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '选择图片';
            app.Panel_3 = uipanel(app.Panel_4);
            app.Panel_3.Title = '参数选择';
            app.Label_9 = uilabel(app.Panel_3);
            app.Label_9.Text = '信道译码方式';
            app.DropDown = uidropdown(app.Panel_3);
            app.DropDown.Items = {'极大似然译码准则', '最大后验概率译码准则'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '极大似然译码准则';
            app.Label_12 = uilabel(app.Panel_3);
            app.Label_12.Text = '信道错误转移概率';
            app.Spinner = uispinner(app.Panel_3);
            app.Spinner.Step = 0.001;
            app.Spinner.Limits = [0 1];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 0.01;
            app.Label_13 = uilabel(app.Panel_3);
            app.Label_13.Text = '信源编码方式';
            app.DropDown_2 = uidropdown(app.Panel_3);
            app.DropDown_2.Items = {'香农编码', '费诺编码', '霍夫曼编码'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '香农编码';
            app.Label_14 = uilabel(app.Panel_3);
            app.Label_14.Text = '信道类型选择';
            app.DropDown_3 = uidropdown(app.Panel_3);
            app.DropDown_3.Items = {'二元对称信道', '二元删除信道'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '二元对称信道';
            app.Button = uibutton(app.Panel_3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始分析';
            app.Panel_2 = uipanel(app.Panel_4);
            app.Panel_2.Title = '有无信道编码得到的输出图像';
            app.UIAxes_2 = uiaxes(app.Panel_2);
            title(app.UIAxes_2, '简单重复编码')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.Panel_2);
            title(app.UIAxes_3, '无信道编码')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '误码率：';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '误码率：';
            app.Label_11 = uilabel(app.Panel_4);
            app.Label_11.Text = '当前状态';
            app.EditField = uieditfield(app.Panel_4, 'text');
            app.EditField.Value = '请选择图片...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = channel_coding

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end