classdef channel_error_rate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI         matlab.ui.Figure
        Panel_4     matlab.ui.container.Panel
        Panel       matlab.ui.container.Panel
        UIAxes      matlab.ui.control.UIAxes
        Button_2    matlab.ui.control.Button
        Panel_3     matlab.ui.container.Panel
        Label_9     matlab.ui.control.Label
        DropDown    matlab.ui.control.DropDown
        Button      matlab.ui.control.Button
        Label_10    matlab.ui.control.Label
        DropDown_2  matlab.ui.control.DropDown
        Label_12    matlab.ui.control.Label
        DropDown_3  matlab.ui.control.DropDown
        Panel_2     matlab.ui.container.Panel
        UIAxes_2    matlab.ui.control.UIAxes
        UIAxes_3    matlab.ui.control.UIAxes
        UIAxes_4    matlab.ui.control.UIAxes
        Label       matlab.ui.control.Label
        Label_2     matlab.ui.control.Label
        Label_3     matlab.ui.control.Label
        Label_4     matlab.ui.control.Label
        Label_5     matlab.ui.control.Label
        Label_6     matlab.ui.control.Label
        Label_13    matlab.ui.control.Label
        Spinner     matlab.ui.control.Spinner
        Label_14    matlab.ui.control.Label
        Spinner_2   matlab.ui.control.Spinner
        Label_15    matlab.ui.control.Label
        Spinner_3   matlab.ui.control.Spinner
        Label_11    matlab.ui.control.Label
        EditField   matlab.ui.control.EditField
    end

    
    properties (Access = private)
        gray_I; % 输入图像的灰度表示
        p;% 错误转移概率
        chan_mod;% 信道类型
        dec_mod;% 信道译码方式
        encd; % 信源编码方法
    end
    

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 初始化所有界面上的组件
            app.EditField.Value = '正在传输中，请稍等...';
            imshow(1, 'Parent', app.UIAxes_2);
            imshow(1, 'Parent', app.UIAxes_3);
            imshow(1, 'Parent', app.UIAxes_4);
            app.Label.Text = '信道容量：';
            app.Label_2.Text = '误码率：';
            
            app.Label_3.Text = '信道容量：';
            app.Label_4.Text = '误码率：';
            
            app.Label_5.Text = '信道容量：';
            app.Label_6.Text = '误码率：';
            drawnow;
            
            [imageH, C, ~, Pe] = gui_channel_error_rate(app.gray_I, app.p, app.encd, app.chan_mod, app.dec_mod);
            
            imshow(uint8(imageH{1}), 'Parent', app.UIAxes_2);
            imshow(uint8(imageH{2}), 'Parent', app.UIAxes_3);
            imshow(uint8(imageH{3}), 'Parent', app.UIAxes_4);
            
            app.Label.Text = strcat('信道容量：', num2str(C(1)));
            app.Label_2.Text = strcat('误码率：', num2str(Pe(1)));
            
            app.Label_3.Text = strcat('信道容量：', num2str(C(2)));
            app.Label_4.Text = strcat('误码率：', num2str(Pe(2)));
            
            app.Label_5.Text = strcat('信道容量：', num2str(C(3)));
            app.Label_6.Text = strcat('误码率：', num2str(Pe(3)));
            drawnow;
            
            app.EditField.Value = '传输完成';
            drawnow;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [fn, pn, ~] = uigetfile('*.bmp;*.tif;*.jpg;*.jpeg;*.png;*.gif;','请选择需要显示的图像');
            I = imread([pn fn]);
            [~, ~, c] = size(I);
            if c == 3 % 判断输入图像是否为灰度图
                app.gray_I = rgb2gray(I);
            else
                app.gray_I = I;
            end
            imshow(app.gray_I, 'Parent', app.UIAxes);
            
            % 清屏
            imshow(1, 'Parent', app.UIAxes_2);
            imshow(1, 'Parent', app.UIAxes_3);
            imshow(1, 'Parent', app.UIAxes_4);
            app.Label.Text = '信道容量：';
            app.Label_2.Text = '误码率：';
            
            app.Label_3.Text = '信道容量：';
            app.Label_4.Text = '误码率：';
            
            app.Label_5.Text = '信道容量：';
            app.Label_6.Text = '误码率：';
            drawnow;
            
            % 设置默认值，即如果不点击其他按钮时，其他按钮的状态
            % 判断信道类型值
            value = app.DropDown.Value;
            switch value
                case '二元对称信道'
                    app.chan_mod = 1;
                case '二元删除信道'
                    app.chan_mod = 2;
            end
            % 判断信道译码方式
            value = app.DropDown_2.Value;
            switch value
                case '极大似然译码准则'
                    app.dec_mod = 1;
                case '最大后验概率译码准则'
                    app.dec_mod = 2;
            end
            
            % 判断信源编码方法
            value = app.DropDown_3.Value;
            switch value
                case '香农编码'
                    app.encd = 1;
                case '费诺编码'
                    app.encd = 2;
                case '霍夫曼编码'
                    app.encd = 3;
            end
            
            % 设置三种信道错误转移概率
            app.p(1) = app.Spinner.Value;
            app.p(2) = app.Spinner_2.Value;
            app.p(3) = app.Spinner_3.Value;
            
            app.EditField.Value = '请选择参数...';
            drawnow;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
            switch value
                case '二元对称信道'
                    app.chan_mod = 1;
                case '二元删除信道'
                    app.chan_mod = 2;
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            
            switch value
                case '极大似然译码准则'
                    app.dec_mod = 1;
                case '最大后验概率译码准则'
                    app.dec_mod = 2;
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            
            switch value
                case '香农编码'
                    app.encd = 1;
                case '费诺编码'
                    app.encd = 2;
                case '霍夫曼编码'
                    app.encd = 3;
            end 
        end

        % Value changed function: Spinner
        function SpinnerValueChanged2(app, event)
            value = app.Spinner.Value;
            
            app.p(1) = value;
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            value = app.Spinner_2.Value;
            
            app.p(2) = value;
        end

        % Value changed function: Spinner_3
        function Spinner_3ValueChanged(app, event)
            value = app.Spinner_3.Value;
            
            app.p(3) = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure;
            app.GUI.Name = 'UI Figure';
            app.Panel_4 = uipanel(app.GUI);
            app.Panel_4.Title = '信道错误转移概率对传输结果的影响';
            app.Panel = uipanel(app.Panel_4);
            app.Panel.Title = '输入图像';
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '选择图片';
            app.Panel_3 = uipanel(app.Panel_4);
            app.Panel_3.Title = '参数选择';
            app.Label_9 = uilabel(app.Panel_3);
            app.Label_9.Text = '选择信道';
            app.DropDown = uidropdown(app.Panel_3);
            app.DropDown.Items = {'二元对称信道', '二元删除信道'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '二元对称信道';
            app.Button = uibutton(app.Panel_3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始分析';
            app.Label_10 = uilabel(app.Panel_3);
            app.Label_10.Text = '信道译码方法';
            app.DropDown_2 = uidropdown(app.Panel_3);
            app.DropDown_2.Items = {'极大似然译码准则', '最大后验概率译码准则'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '极大似然译码准则';
            app.Label_12 = uilabel(app.Panel_3);
            app.Label_12.Text = '信源编码方法';
            app.DropDown_3 = uidropdown(app.Panel_3);
            app.DropDown_3.Items = {'香农编码', '费诺编码', '霍夫曼编码'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '香农编码';
            app.Panel_2 = uipanel(app.Panel_4);
            app.Panel_2.Title = '不同的信道错误转移概率得到的输出图像';
            app.UIAxes_2 = uiaxes(app.Panel_2);
            title(app.UIAxes_2, '概率1')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.Panel_2);
            title(app.UIAxes_3, '概率2')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.Panel_2);
            title(app.UIAxes_4, '概率3')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '信道容量：';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '误码率：';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '信道容量：';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '误码率：';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '信道容量：';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '误码率：';
            app.Label_13 = uilabel(app.Panel_2);
            app.Label_13.Text = '概率1';
            app.Spinner = uispinner(app.Panel_2);
            app.Spinner.Step = 0.001;
            app.Spinner.Limits = [0 1];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged2, true);
            app.Spinner.Value = 0.01;
            app.Label_14 = uilabel(app.Panel_2);
            app.Label_14.Text = '概率2';
            app.Spinner_2 = uispinner(app.Panel_2);
            app.Spinner_2.Step = 0.001;
            app.Spinner_2.Limits = [0 1];
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Value = 0.05;
            app.Label_15 = uilabel(app.Panel_2);
            app.Label_15.Text = '概率3';
            app.Spinner_3 = uispinner(app.Panel_2);
            app.Spinner_3.Step = 0.001;
            app.Spinner_3.Limits = [0 10];
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_3ValueChanged, true);
            app.Spinner_3.Value = 0.1;
            app.Label_11 = uilabel(app.Panel_4);
            app.Label_11.Text = '当前状态';
            app.EditField = uieditfield(app.Panel_4, 'text');
            app.EditField.Value = '请选择图片...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = channel_error_rate

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end