classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Menu              matlab.ui.container.Menu
        Menu_2            matlab.ui.container.Menu
        Menu_3            matlab.ui.container.Menu
        Menu_4            matlab.ui.container.Menu
        Menu_5            matlab.ui.container.Menu
        Menu_6            matlab.ui.container.Menu
        Menu_7            matlab.ui.container.Menu
        Menu_8            matlab.ui.container.Menu
        AttrP             matlab.ui.container.Panel
        Panel_3           matlab.ui.container.Panel
        Label_6           matlab.ui.control.Label
        se                matlab.ui.control.DropDown
        Label_7           matlab.ui.control.Label
        ce                matlab.ui.control.DropDown
        RP                matlab.ui.container.Panel
        Label_8           matlab.ui.control.Label
        rd                matlab.ui.control.DropDown
        Label_9           matlab.ui.control.Label
        sd                matlab.ui.control.DropDown
        Button_2          matlab.ui.control.Button
        Panel_4           matlab.ui.container.Panel
        Label_5           matlab.ui.control.Label
        false_effi        matlab.ui.control.Spinner
        Label_10          matlab.ui.control.Label
        DropDown          matlab.ui.control.DropDown
        imgP              matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        Label_4           matlab.ui.control.Label
        Button            matlab.ui.control.Button
        info              matlab.ui.control.Label
        avlenL            matlab.ui.control.Label
        code_effi         matlab.ui.control.Label
        channel_cap       matlab.ui.control.Label
        avg_false_effi    matlab.ui.control.Label
        avg_false_effi_2  matlab.ui.control.Label
        Title             matlab.ui.control.Label
    end

    
    properties (Access = private)
        gray_I % 灰度图
        encd; % 信源编码方式
        p; % 错误转移概率
        chan_mod;% 信道类型
        dec_mod;% 信道译码方式
    end
    

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [fn, pn, ~] = uigetfile('*.bmp;*.tif;*.jpg;*.jpeg;*.png;*.gif;','请选择需要显示的图像');
            I = imread([pn fn]);
            [~, ~, c] = size(I);
            if c == 3 % 判断输入图像是否为灰度图
                app.gray_I = rgb2gray(I);
            else
                app.gray_I = I;
            end
            imshow(app.gray_I, 'Parent', app.UIAxes);
            app.info.Text = '等待选择参数...';
            
            % 清屏
            imshow(1, 'Parent', app.UIAxes_2);
            app.avlenL.Text = '平均码长：';
            app.code_effi.Text = '信息传输率：';
            
            app.channel_cap.Text = '信道容量：';
            app.avg_false_effi_2.Text = '误码率：';
            app.avg_false_effi.Text = '平均错误概率：';
            
            drawnow;
            
            % 在这里给下拉列表设置一些默认值
            
            % 判断信道类型
            value = app.DropDown.Value;
            switch value
                case '二元对称信道'
                    app.chan_mod = 1;
                case '二元删除信道'
                    app.chan_mod = 2;
            end
            
            % 判断信源编码方法
            value = app.se.Value;
            switch value
                case '香农编码'
                    app.encd = 1;
                    
                case '费诺编码'
                    app.encd = 2;
                    
                case '霍夫曼编码'
                    app.encd = 3;
            end
            
            % 得到信道错误转移概率
            app.p = app.false_effi.Value;
            
            % 判断信道译码方法
            value = app.rd.Value;
            switch value
                case '极大似然译码准则'
                    app.chan_mod = 1;
                case '最大后验概率译码准则'
                    app.chan_mod = 2;
            end
            drawnow;
        end

        % Value changed function: se
        function seValueChanged(app, event)
            value = app.se.Value;
            
            switch value
                case '香农编码'
                    app.encd = 1;
                    
                case '费诺编码'
                    app.encd = 2;
                    
                case '霍夫曼编码'
                    app.encd = 3;
            end
            drawnow;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.info.Text = '传输中请稍等...';

            % 清屏
            imshow(1, 'Parent', app.UIAxes_2);
            app.avlenL.Text = '平均码长：';
            app.code_effi.Text = '信息传输率：';
            
            app.channel_cap.Text = '信道容量：';
            app.avg_false_effi_2.Text = '误码率：';
            app.avg_false_effi.Text = '平均错误概率：';
            drawnow;
            
            tic;
            [imageH, P1, avlen, C, Pe1, Pe2] = fun_for_gui(app.gray_I, app.p, app.dec_mod, app.chan_mod, app.encd);
            toc;
            imageH = uint8(imageH);
            imshow(imageH, 'Parent', app.UIAxes_2);
            
            app.avlenL.Text = strcat('编码的平均码长：', num2str(avlen));% 在APP上显示
            app.code_effi.Text = strcat("信息传输率：", num2str(P1));% 在APP上显示
            app.channel_cap.Text = strcat("信道容量：", num2str(C));% 在APP上显示
            app.avg_false_effi.Text = strcat("平均错误概率：", num2str(Pe1));% 在APP上显示
            app.avg_false_effi_2.Text = strcat("误码率：", num2str(Pe2));% 在APP上显示
            app.info.Text = '传输完成';
        end

        % Value changed function: false_effi
        function false_effiValueChanged(app, event)
            value = app.false_effi.Value;
            app.p = value;
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            % 信源编码测试
            run source.mlapp;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case '二元对称信道'
                    app.chan_mod = 1;
                case '二元删除信道'
                    app.chan_mod = 2;
            end
        end

        % Value changed function: rd
        function rdValueChanged(app, event)
            value = app.rd.Value;
            
            switch value
                case '极大似然译码准则'
                    app.chan_mod = 1;
                case '最大后验概率译码准则'
                    app.chan_mod = 2;
            end
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            % 有无信道编码的测试
            run channel_coding.mlapp;
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            % 信道译码测试
            run channel_decode.mlapp;
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            % 信道类型测试
            run channel_type.mlapp;
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            run channel_error_rate.mlapp;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '发送端测试';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '信源编码';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '信道编码';
            app.Menu_4 = uimenu(app.UIFigure);
            app.Menu_4.Text = '信道测试';
            app.Menu_5 = uimenu(app.Menu_4);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '信道错误转移概率';
            app.Menu_6 = uimenu(app.Menu_4);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '信道类型';
            app.Menu_7 = uimenu(app.UIFigure);
            app.Menu_7.Text = '接收端测试';
            app.Menu_8 = uimenu(app.Menu_7);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '信道译码';
            app.AttrP = uipanel(app.UIFigure);
            app.AttrP.Title = '参数设置';
            app.Panel_3 = uipanel(app.AttrP);
            app.Panel_3.Title = '发送端参数';
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '信源编码';
            app.se = uidropdown(app.Panel_3);
            app.se.Items = {'香农编码', '费诺编码', '霍夫曼编码'};
            app.se.ValueChangedFcn = createCallbackFcn(app, @seValueChanged, true);
            app.se.Value = '香农编码';
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = '信道编码';
            app.ce = uidropdown(app.Panel_3);
            app.ce.Items = {'简单重复编码'};
            app.ce.Enable = 'off';
            app.ce.Value = '简单重复编码';
            app.RP = uipanel(app.AttrP);
            app.RP.Title = '接收端参数';
            app.Label_8 = uilabel(app.RP);
            app.Label_8.Text = '信道译码';
            app.rd = uidropdown(app.RP);
            app.rd.Items = {'极大似然译码准则', '最大后验概率译码准则'};
            app.rd.ValueChangedFcn = createCallbackFcn(app, @rdValueChanged, true);
            app.rd.Value = '极大似然译码准则';
            app.Label_9 = uilabel(app.RP);
            app.Label_9.Text = '信源译码';
            app.sd = uidropdown(app.RP);
            app.sd.Items = {'无失真译码', ''};
            app.sd.Enable = 'off';
            app.sd.Value = '无失真译码';
            app.Button_2 = uibutton(app.AttrP, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '开始传输';
            app.Panel_4 = uipanel(app.AttrP);
            app.Panel_4.Title = '信道参数';
            app.Label_5 = uilabel(app.Panel_4);
            app.Label_5.Text = '信道错误转移概率';
            app.false_effi = uispinner(app.Panel_4);
            app.false_effi.Step = 0.001;
            app.false_effi.Limits = [0 1];
            app.false_effi.ValueDisplayFormat = '%.3f';
            app.false_effi.ValueChangedFcn = createCallbackFcn(app, @false_effiValueChanged, true);
            app.false_effi.Value = 0.01;
            app.Label_10 = uilabel(app.Panel_4);
            app.Label_10.Text = '信道类型';
            app.DropDown = uidropdown(app.Panel_4);
            app.DropDown.Items = {'二元对称信道', '二元删除信道'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '二元对称信道';
            app.imgP = uipanel(app.UIFigure);
            app.imgP.Title = '图片显示区域';
            app.UIAxes = uiaxes(app.imgP);
            title(app.UIAxes, '原始图像')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.imgP);
            title(app.UIAxes_2, '传输后得到的图像')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.Label_4 = uilabel(app.imgP);
            app.Label_4.Text = '===========================>';
            app.Button = uibutton(app.imgP, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择图片';
            app.info = uilabel(app.imgP);
            app.info.Text = '等待选择图片...';
            app.avlenL = uilabel(app.imgP);
            app.avlenL.Text = '编码的平均码长：';
            app.code_effi = uilabel(app.imgP);
            app.code_effi.Text = '信息传输率：';
            app.channel_cap = uilabel(app.imgP);
            app.channel_cap.Text = '信道容量：';
            app.avg_false_effi = uilabel(app.imgP);
            app.avg_false_effi.Text = '平均错误概率：';
            app.avg_false_effi_2 = uilabel(app.imgP);
            app.avg_false_effi_2.Text = '误码率：';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = '信息论教学系统';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end